classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure        matlab.ui.Figure
        PreprocessorPanel     matlab.ui.container.Panel
        ProblemTypeDropDown   matlab.ui.control.DropDown
        ProblemLocationLabel  matlab.ui.control.Label
        ProblemLocationText   matlab.ui.control.EditField
        GeometryFileButton    matlab.ui.control.Button
        GeometryFileLabel     matlab.ui.control.Label
        GeometryFileText      matlab.ui.control.EditField
        SettingsFilePanel     matlab.ui.container.Panel
        SettingsFileLabel     matlab.ui.control.Label
        SettingsFileText      matlab.ui.control.EditField
        SettingsFileButton    matlab.ui.control.Button
        ElementTypePanel      matlab.ui.container.Panel
        ElementTypeListBox    matlab.ui.control.ListBox
        ElementTypeLabel      matlab.ui.control.Label
        MeshButton            matlab.ui.control.Button
        MeshLamp              matlab.ui.control.Lamp
        SolveLamp             matlab.ui.control.Lamp
        SolveButton           matlab.ui.control.Button
        ProblemTypeLabel      matlab.ui.control.Label
        SaveSetupButton       matlab.ui.control.Button
        SaveSetupLamp         matlab.ui.control.Lamp
        PostprocessorPanel    matlab.ui.container.Panel
        LogPanel              matlab.ui.container.Panel
        LogArea               matlab.ui.control.TextArea
        ClearlogButton        matlab.ui.control.Button
        SavetofileButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        state_machine_state = 'select_geometry';
        
        problem_name = '';
        problem_location = '';
        geometry_file = '';
        settings_file = '';
        mesh_file = '';
        setup_file = '';
        
        element_type;
        
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        % ============== Setup functions for groups of elements ==============
        
        % === UI-elemements for settings file selection
        function enable_settings_file_selection(app)
            %app.SettingsFileText.Enable = true;
            app.SettingsFileText.Enable = 'on';
            app.SettingsFileButton.Enable = true;
            
            % Just for safety: Clear value
            app.SettingsFileText.Value = '';
        end
        
        function disable_settings_file_selection(app)
            app.SettingsFileText.Enable = false;
            app.SettingsFileButton.Enable = false;
            
            app.SettingsFileText.Value = '';
        end
        
        % === UI-elemements for element type selection
        function enable_element_type_selection(app)
            app.ElementTypeListBox.Enable = true;
        end
        
        function disable_element_type_selection(app)
            app.ElementTypeListBox.Enable = false;
        end
        
        % UI-elements for meshing procedure
        function enable_mesh_ui_elements(app)
            app.MeshButton.Enable = true;
            app.MeshLamp.Color = [1,1,0];
            app.MeshLamp.Enable = true;
        end
        
        function disable_mesh_ui_elements(app)
            app.MeshButton.Enable = false;
            app.MeshLamp.Color = [1,1,0];
            app.MeshLamp.Enable = false;
        end
        
        % UI-elements for saving setup to file
        function enable_setup_saving_ui_elements(app)
            app.SaveSetupButton.Enable = true;
            app.SaveSetupLamp.Color = [1,1,0];
            app.SaveSetupLamp.Enable = true;
        end
        
        function disable_setup_saving_ui_elements(app)
            app.SaveSetupButton.Enable = false;
            app.SaveSetupLamp.Color = [1,1,0];
            app.SaveSetupLamp.Enable = false;
        end
        
        % UI-elements for saving setup to file
        function enable_problem_solving_ui_elements(app)
            app.SolveButton.Enable = true;
            app.SolveLamp.Color = [1,1,0];
            app.SolveLamp.Enable = true;
        end
        
        function disable_problem_solving_ui_elements(app)
            app.SolveButton.Enable = false;
            app.SolveLamp.Color = [1,1,0];
            app.SolveLamp.Enable = false;
        end
        
        
        % ============== State transitions ==============
        
        % === Transitions from state 'select_geometry'
        function select_geometry_finished_transition(app)
            app.state_machine_state = 'select_settings';
            app.enable_settings_file_selection();
            
            % Element type selection can also be enabled because
            % it is independend from settings file selection
            app.enable_element_type_selection();
            
        end
        
        % === Transitions from state 'select_settings'
        function select_settings_finished(app)
            app.state_machine_state = 'save';
            app.enable_setup_saving_ui_elements();
        end
        
        % === Transitions from 'save' state
        function save_state_new_geometry_file_transition(app)
            app.state_machine_state = 'select_settings';
            app.disable_setup_saving_ui_elements();
        end
        
        function save_state_new_settings_file_transition(app)
            app.state_machine_state = 'save';
            app.SaveSetupLamp.Color = [1,1,0]; % Set to yellow color
        end
        
        function saving_finished_transition(app)
            app.state_machine_state = 'mesh';
            app.enable_mesh_ui_elements();
            app.SaveSetupLamp.Color = [0,1,0];
        end
        
        % === Transitions from 'mesh' state
        function mesh_new_geometry_file_transition(app)
            app.state_machine_state = 'select_settings';
            app.disable_setup_saving_ui_elements();
            app.disable_mesh_ui_elements();
            
        end
        
        function mesh_new_settings_file_transition(app)
            app.state_machine_state = 'save';
            app.disable_mesh_ui_elements();
            app.SaveSetupLamp.Color = [1,1,0];
        end
        
        function meshing_finished(app)
            app.state_machine_state = 'solve';
            app.enable_problem_solving_ui_elements();
            app.MeshLamp.Color = [0,1,0];
        end
        
        % === Transitions from 'solve' state
        function solve_new_geometry_file_transition(app)
            app.state_machine_state = 'select_settings';
            app.disable_setup_saving_ui_elements();
            app.disable_mesh_ui_elements();
            app.disable_problem_solving_ui_elements();
            
        end
        
        function solve_new_settings_file_transition(app)
            app.state_machine_state = 'save';
            app.disable_mesh_ui_elements();
            app.disable_problem_solving_ui_elements();
            app.SaveSetupLamp.Color = [1,1,0];
        end
        
        function solving_finished(app)
            app.SolveLamp.Color = [0,1,0];
            app.SolveLamp.Enable = true;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            close all
            clc
            
            % Assign FEM-types to a GUI-specific number. e.g. a First order triangle is set to the number 1 etc.
            % This assignment can also be done in the AppDesigner, but then the ItemsData variable would contain string
            % values instead of numeric values. ({'1', '2', '3'} instead of [1,2,3])
            app.ElementTypeListBox.ItemsData = [1,2,3];
            
            % Global flag indicating that the graphical user interface is used.
            global ui;
            ui = true;
            
            % The 'Misc' entity has functions implemented allowing simultaneous writing to console and log area in the GUI in UI mode
            % or only to console in console mode. For writing to the GUI Log-area it needs a reference to it. See Misc.print_message()
            % for further information.
            global msg_dest;
            msg_dest = app.LogArea;
        end

        % Button pushed function: GeometryFileButton
        function GeometryFileButtonPushed(app, event)
            
            % ===== Actions
            [file, path] = uigetfile('*.geo', 'Select geometry file');
            
            %No file chosen
            if file == 0
                return
            end
            
            [~, name, ~] = fileparts(file);
            
            app.GeometryFileText.Value = file;
            app.ProblemLocationText.Value = path;
            
            app.problem_location = path;
            app.problem_name = name;
            app.geometry_file = file;
            
            % ===== Transitions
            switch app.state_machine_state
                case 'select_geometry'
                    app.select_geometry_finished_transition();
                case 'select_settings'
                    %No action requires
                case 'save'
                    app.save_state_new_geometry_file_transition();
                case 'mesh'
                    app.mesh_new_geometry_file_transition();
                case 'solve'
                    app.solve_new_geometry_file_transition();
                otherwise
                    print_error_message('Internal GUI state machine error: invalid state')
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            Misc.print_warning_message('Problem solving is not implemented yet.')
            app.solving_finished();
        end

        % Value changed function: ElementTypeListBox
        function ElementTypeListBoxValueChanged(app, event)
            app.element_type = app.ElementTypeListBox.Value;
        end

        % Button pushed function: SettingsFileButton
        function SettingsFileButtonPushed(app, event)
            % ===== Actions
            
            
            % Most times, the settings file is in the same folder as the geometry file.
            % To avoid too much clicking, the current working directory temporary changes to the problem location.
            tmp = pwd;
            
            cd(app.problem_location)
            [file, ~] = uigetfile('*.set', 'Select settings file');
            
            %No file chosen
            if file == 0
                return
            end
            
            cd(tmp);
            clear tmp;
            
            app.SettingsFileText.Value = file;
            app.settings_file = file;
            
            % ===== Transitions
            switch app.state_machine_state
                case 'select_settings'
                    app.select_settings_finished();
                case 'save'
                    app.save_state_new_settings_file_transition();
                case 'mesh'
                    app.mesh_new_settings_file_transition();
                case 'solve'
                    app.solve_new_settings_file_transition();
                otherwise
                    print_error_message('Internal GUI state machine error: invalid state')
            end
        end

        % Button pushed function: SaveSetupButton
        function SaveSetupButtonPushed(app, event)
            Misc.print_warning_message('Saving setup to file in currently not supported and will be implemented later.')
            
            % ===== Transitions
            switch app.state_machine_state
                case 'save'
                    app.saving_finished_transition();
                otherwise
                    print_error_message('Internal GUI state machine error: invalid state')
            end
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            Misc.print_warning_message('Meshing is not implemented yet.')
            
            % ===== Transitions
            switch app.state_machine_state
                case 'mesh'
                    app.meshing_finished();
                otherwise
                    print_error_message('Internal GUI state machine error: invalid state')
            end
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogArea.Value = {''};
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [filename, ~] = uiputfile('*.log', 'Save log-file');
            if filename == 0
                return
            end
            
            file = fopen(filename, 'w');
            N_lines = length(app.LogArea.Value);
            for k = 1 : N_lines
                tmp = app.LogArea.Value{k};
                fprintf(file, [tmp, '\n']);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.PreprocessorPanel = uipanel(app.FEMLabUIFigure);
            app.PreprocessorPanel.Title = 'Preprocessor';
            app.ProblemTypeDropDown = uidropdown(app.PreprocessorPanel);
            app.ProblemTypeDropDown.Items = {'Electrostatic, plane', 'Static current, plane'};
            app.ProblemTypeDropDown.Value = 'Electrostatic, plane';
            app.ProblemLocationLabel = uilabel(app.PreprocessorPanel);
            app.ProblemLocationLabel.Text = 'Problem location';
            app.ProblemLocationText = uieditfield(app.PreprocessorPanel, 'text');
            app.ProblemLocationText.Editable = 'off';
            app.GeometryFileButton = uibutton(app.PreprocessorPanel, 'push');
            app.GeometryFileButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryFileButtonPushed, true);
            app.GeometryFileButton.Text = 'Select geometry';
            app.GeometryFileLabel = uilabel(app.PreprocessorPanel);
            app.GeometryFileLabel.Text = 'Geometry file';
            app.GeometryFileText = uieditfield(app.PreprocessorPanel, 'text');
            app.GeometryFileText.Editable = 'off';
            app.GeometryFileText.Enable = 'off';
            app.SettingsFilePanel = uipanel(app.PreprocessorPanel);
            app.SettingsFileLabel = uilabel(app.SettingsFilePanel);
            app.SettingsFileLabel.Text = 'Settings file';
            app.SettingsFileText = uieditfield(app.SettingsFilePanel, 'text');
            app.SettingsFileText.Editable = 'off';
            app.SettingsFileText.Enable = 'off';
            app.SettingsFileButton = uibutton(app.SettingsFilePanel, 'push');
            app.SettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsFileButtonPushed, true);
            app.SettingsFileButton.Enable = 'off';
            app.SettingsFileButton.Text = 'Select settings';
            app.ElementTypePanel = uipanel(app.PreprocessorPanel);
            app.ElementTypeListBox = uilistbox(app.ElementTypePanel);
            app.ElementTypeListBox.Items = {'First order triangle', 'Second order triangle', 'Third order triangle'};
            app.ElementTypeListBox.ValueChangedFcn = createCallbackFcn(app, @ElementTypeListBoxValueChanged, true);
            app.ElementTypeListBox.Enable = 'off';
            app.ElementTypeListBox.Value = 'First order triangle';
            app.ElementTypeLabel = uilabel(app.ElementTypePanel);
            app.ElementTypeLabel.Text = 'Select element-type';
            app.MeshButton = uibutton(app.PreprocessorPanel, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Enable = 'off';
            app.MeshButton.Text = 'Mesh';
            app.MeshLamp = uilamp(app.PreprocessorPanel);
            app.MeshLamp.Enable = 'off';
            app.SolveLamp = uilamp(app.PreprocessorPanel);
            app.SolveLamp.Enable = 'off';
            app.SolveButton = uibutton(app.PreprocessorPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Enable = 'off';
            app.SolveButton.Text = 'Solve';
            app.ProblemTypeLabel = uilabel(app.PreprocessorPanel);
            app.ProblemTypeLabel.Text = 'Problem type';
            app.SaveSetupButton = uibutton(app.PreprocessorPanel, 'push');
            app.SaveSetupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSetupButtonPushed, true);
            app.SaveSetupButton.Enable = 'off';
            app.SaveSetupButton.Text = 'Save setup';
            app.SaveSetupLamp = uilamp(app.PreprocessorPanel);
            app.SaveSetupLamp.Enable = 'off';
            app.PostprocessorPanel = uipanel(app.FEMLabUIFigure);
            app.PostprocessorPanel.Title = 'Postprocessor';
            app.LogPanel = uipanel(app.FEMLabUIFigure);
            app.LogPanel.Title = 'Log';
            app.LogArea = uitextarea(app.LogPanel);
            app.LogArea.Editable = 'off';
            app.LogArea.Value = {''; ''};
            app.ClearlogButton = uibutton(app.LogPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SavetofileButton = uibutton(app.LogPanel, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_window

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end